classdef AN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes           matlab.ui.control.UIAxes
        Label            matlab.ui.control.Label
        xEditFieldLabel  matlab.ui.control.Label
        xEditField       matlab.ui.control.EditField
        yEditFieldLabel  matlab.ui.control.Label
        yEditField       matlab.ui.control.EditField
        Button           matlab.ui.control.Button
        Label_2          matlab.ui.control.Label
        evoluteLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;
            sym t;
            t = sym('t');
            x1 = app.xEditField.Value;
            y1 = app.yEditField.Value;
            x = str2sym(x1);
            y = str2sym(y1);
            evoluta1(x, y, t);
            function [ksi, eta] = evoluta1(x, y, t)
            dx = diff(x, t);
            ddx = diff(dx, t);
            dy = diff(y, t);
            ddy = diff(dy, t);

            right_member = (dx^2 + dy^2)/(dx*ddy - ddx*dy);
            ksi = x - dy * right_member;
            eta = y + dx * right_member;
            ksi = simplify(ksi);
            eta = simplify(eta);
            app.Label.Text = char(ksi);
            app.Label_2.Text = char(eta);
            grafik(ksi, eta, -10, 10);
            end

            function [] = grafik(x, y, alpha, beta)
            ts = [alpha:0.01:beta];
            xs = vpa(subs(x, ts));
            ys = vpa(subs(y, ts));

            plot(app.UIAxes, xs, ys);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x = ';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y = ';
            app.yEditField = uieditfield(app.UIFigure, 'text');
            app.evoluteLabel = uilabel(app.UIFigure);
            app.evoluteLabel.Text = 'evolute : ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end